classdef RobotControl_GUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1         matlab.ui.Figure
        random_btn      matlab.ui.control.Button
        stop_button     matlab.ui.control.Button
        start_button    matlab.ui.control.Button
        back_obstacle   matlab.ui.control.EditField
        text23          matlab.ui.control.Label
        text22          matlab.ui.control.Label
        right_obstacle  matlab.ui.control.EditField
        text21          matlab.ui.control.Label
        text15          matlab.ui.control.Label
        text14          matlab.ui.control.Label
        setbutton       matlab.ui.control.Button
        left_obs        matlab.ui.control.Slider
        right_obs       matlab.ui.control.Slider
        back_obs        matlab.ui.control.Slider
        front_obs       matlab.ui.control.Slider
        target_lon      matlab.ui.control.EditField
        target_lat      matlab.ui.control.EditField
        front_obstacle  matlab.ui.control.EditField
        text7           matlab.ui.control.Label
        text6           matlab.ui.control.Label
        text5           matlab.ui.control.Label
        text4           matlab.ui.control.Label
        left_obstacle   matlab.ui.control.EditField
        text3           matlab.ui.control.Label
        text1           matlab.ui.control.Label
    end

    
    methods (Access = private)
        function model_open(app, handles)
            % Make sure the diagram is still open
              if  isempty(find_system('Name','FuzzyMobileRobotGuidance')),
                open_system('FuzzyMobileRobotGuidance');
                open_system('FuzzyMobileRobotGuidance/Robot/Obstacle Sensor')
                   %set_param('FuzzyMobileRobotGuidance/Robot/Obstacle Sensor/Front Gain','Position',[275 14 340 56])
                    % Put  values of Kf and Ki from the GUI into the Block dialogs
                set_param('FuzzyMobileRobotGuidance/Robot/Obstacle Sensor/Left Gain','Gain',...
                                        get(handles.left_obs,'String'))
                set_param('FuzzyMobileRobotGuidance/Robot/Obstacle Sensor/Front Gain','Gain',...
                                        get(handles.front_obs,'String'))
                set_param('FuzzyMobileRobotGuidance/Robot/Obstacle Sensor/Right Gain','Gain',...
                                        get(handles.right_obs,'String'))
                set_param('FuzzyMobileRobotGuidance/Robot/Obstacle Sensor/Back Gain','Gain',...
                                        get(handles.back_obs,'String'))
                set_param('FuzzyMobileRobotGuidance/Target Location','Value',...
                                        get(handles.target_lat,'String') ,'Value',get(handles.target_lon,'String'))
            
              end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function RobotControl_GUI_OpeningFcn(app, varargin)
            % --- Executes just before RobotControl_GUI is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   unrecognized PropertyName/PropertyValue pairs from the
            %            command line (see VARARGIN)
            model_open(app, handles)
            % Choose default command line output for RobotControl_GUI
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            num=get(handles.front_obs,'Value');
            set(handles.front_obstacle,'String',num);
            num=get(handles.left_obs,'Value');
            set(handles.left_obstacle,'String',num);
            num=get(handles.right_obs,'Value');
            set(handles.right_obstacle,'String',num);
            num=get(handles.back_obs,'Value');
            set(handles.back_obstacle,'String',num);
        end

        % Value changed function: back_obs
        function back_obs_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to back_obs (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            num=get(hObject,'Value');
            set(handles.back_obstacle,'String',num);
            model_open(app, handles)
            set_param('FuzzyMobileRobotGuidance/Robot/Obstacle Sensor/Back Gain','Gain',num2str(num))
        end

        % Value changed function: front_obs
        function front_obs_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to front_obs (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            num=get(hObject,'Value');
            set(handles.front_obstacle,'String',num);
            model_open(app, handles)
            set_param('FuzzyMobileRobotGuidance/Robot/Obstacle Sensor/Front Gain','Gain',num2str(num))
        end

        % Value changed function: left_obs
        function left_obs_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to left_obs (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            num=get(hObject,'Value');
            set(handles.left_obstacle,'String',num);
            model_open(app, handles)
            set_param('FuzzyMobileRobotGuidance/Robot/Obstacle Sensor/Left Gain','Gain',num2str(num))
        end

        % Button pushed function: random_btn
        function random_btn_Callback(app, event)
            % --- Executes on button press in random_btn.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to random_btn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            vrclose;
            world =  vrworld('Robot','reuse');
            open(world);
            fig =vrfigure(world);
            Obstacle=vrnode(world,'Obstacle');
            Buil=vrnode(world,'Building3');
            Buil2=vrnode(world,'Building2');
            Buil1=vrnode(world,'Building1');
            Rose=vrnode(world,'Rose');
            Tree4=vrnode(world,'Tree4');
            Tree2=vrnode(world,'Tree2');
            Tree1=vrnode(world,'Tree1');
            palm_tree2=vrnode(world,'palm_tree2');
            palm_tree=vrnode(world,'palm_tree');
            Car=vrnode(world,'Car');
            set(fig, 'Viewpoint', 'side_view');
                T1=randi([-1000 1500],1,3);
                T2=randi([-1000 1500],1,3);
                T3=randi([-1000 1500],1,3);
                T4=randi([-1000 1500],1,3);
                T5=randi([-1000 1500],1,3);
                T6=randi([-1000 1500],1,3);
                T7=randi([-1000 1500],1,3);
                T8=randi([-1000 1500],1,3);
                T9=randi([-1000 1500],1,3);
                T10=randi([-1000 1500],1,3);
                Obstacle.translation=[T1(1,1) 0 T1(1,3)];
                Buil.translation=[T2(1,1) 0 T2(1,3)];
                Buil2.translation=[T3(1,1) 0 T3(1,3)];
                Buil1.translation=[T4(1,1) 0 T4(1,3)];
                Rose.translation=[T5(1,1) 0 T5(1,3)];
                Tree4.translation=[T6(1,1) 0 T6(1,3)];
                Tree2.translation=[T7(1,1) 0 T7(1,3)];
                Tree1.translation=[T8(1,1) 0 T8(1,3)];
                palm_tree2.translation=[T9(1,1) 0 T9(1,3)];
                palm_tree.translation=[T10(1,1) 0 T10(1,3)];
                p1=Obstacle.translation;
                p2=Buil.translation;
                p3=Car.translation;
                display(p1);
                display(p3);
                theta= atan((p1(1,3)-p3(1,3))/(p1(1,1)-p3(1,1)));
                %theta=direction(p3(1,1),p3(1,3),p1(1,1),p1(1,3));
                display(theta);
                vrdrawnow;
        end

        % Value changed function: right_obs
        function right_obs_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to right_obs (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            num=get(hObject,'Value');
            set(handles.right_obstacle,'String',num);
            model_open(app, handles)
            set_param('FuzzyMobileRobotGuidance/Robot/Obstacle Sensor/Right Gain','Gain',num2str(num))
        end

        % Button pushed function: setbutton
        function setbutton_Callback(app, event)
            % --- Executes on button press in setbutton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to setbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lat=get(handles.target_lat,'String');
            lon=get(handles.target_lon,'String');
            loc=strcat('[',lat,', ', lon,']');
            set_param('FuzzyMobileRobotGuidance/TargetLocation','Value',loc)
        end

        % Button pushed function: start_button
        function start_button_Callback(app, event)
            % --- Executes on button press in start_button.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to start_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            sim('FuzzyMobileRobotGuidance');
        end

        % Button pushed function: stop_button
        function stop_button_Callback(app, event)
            % --- Executes on button press in stop_button.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to stop_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set_param('FuzzyMobileRobotGuidance','SimulationCommand','stop')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'RobotControl_GUI';
            app.figure1.Tag = 'figure1';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Mobile Robot Motion Control Board';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Target Location';
            app.left_obstacle = uieditfield(app.figure1, 'text');
            app.left_obstacle.Tag = 'left_obstacle';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Left:';
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Front:';
            app.text6 = uilabel(app.figure1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Lat:';
            app.text7 = uilabel(app.figure1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Lon:';
            app.front_obstacle = uieditfield(app.figure1, 'text');
            app.front_obstacle.Tag = 'front_obstacle';
            app.target_lat = uieditfield(app.figure1, 'text');
            app.target_lat.Tag = 'target_lat';
            app.target_lat.Value = '10.049503';
            app.target_lon = uieditfield(app.figure1, 'text');
            app.target_lon.Tag = 'target_lon';
            app.target_lon.Value = '39.777406';
            app.front_obs = uislider(app.figure1);
            app.front_obs.Limits = [0 2];
            app.front_obs.MajorTicks = [];
            app.front_obs.ValueChangedFcn = createCallbackFcn(app, @front_obs_Callback, true);
            app.front_obs.MinorTicks = [];
            app.front_obs.Tag = 'front_obs';
            app.front_obs.Value = 2;
            app.back_obs = uislider(app.figure1);
            app.back_obs.Limits = [0 2];
            app.back_obs.MajorTicks = [];
            app.back_obs.ValueChangedFcn = createCallbackFcn(app, @back_obs_Callback, true);
            app.back_obs.MinorTicks = [];
            app.back_obs.Tag = 'back_obs';
            app.back_obs.Value = 1;
            app.right_obs = uislider(app.figure1);
            app.right_obs.Limits = [0 2];
            app.right_obs.MajorTicks = [];
            app.right_obs.ValueChangedFcn = createCallbackFcn(app, @right_obs_Callback, true);
            app.right_obs.MinorTicks = [];
            app.right_obs.Tag = 'right_obs';
            app.right_obs.Value = 1.3;
            app.left_obs = uislider(app.figure1);
            app.left_obs.Limits = [0 2];
            app.left_obs.MajorTicks = [];
            app.left_obs.ValueChangedFcn = createCallbackFcn(app, @left_obs_Callback, true);
            app.left_obs.MinorTicks = [];
            app.left_obs.Tag = 'left_obs';
            app.left_obs.Value = 1;
            app.setbutton = uibutton(app.figure1, 'push');
            app.setbutton.ButtonPushedFcn = createCallbackFcn(app, @setbutton_Callback, true);
            app.setbutton.Tag = 'setbutton';
            app.setbutton.Text = 'Set';
            app.text14 = uilabel(app.figure1);
            app.text14.Tag = 'text14';
            app.text14.Text = 'deg';
            app.text15 = uilabel(app.figure1);
            app.text15.Tag = 'text15';
            app.text15.Text = 'deg';
            app.text21 = uilabel(app.figure1);
            app.text21.Tag = 'text21';
            app.text21.Text = 'Obstacles';
            app.right_obstacle = uieditfield(app.figure1, 'text');
            app.right_obstacle.Tag = 'right_obstacle';
            app.text22 = uilabel(app.figure1);
            app.text22.Tag = 'text22';
            app.text22.Text = 'Right';
            app.text23 = uilabel(app.figure1);
            app.text23.Tag = 'text23';
            app.text23.Text = 'Back';
            app.back_obstacle = uieditfield(app.figure1, 'text');
            app.back_obstacle.Tag = 'back_obstacle';
            app.start_button = uibutton(app.figure1, 'push');
            app.start_button.ButtonPushedFcn = createCallbackFcn(app, @start_button_Callback, true);
            app.start_button.Tag = 'start_button';
            app.start_button.Text = 'Start Simulation';
            app.stop_button = uibutton(app.figure1, 'push');
            app.stop_button.ButtonPushedFcn = createCallbackFcn(app, @stop_button_Callback, true);
            app.stop_button.Tag = 'stop_button';
            app.stop_button.Text = 'Stop';
            app.random_btn = uibutton(app.figure1, 'push');
            app.random_btn.ButtonPushedFcn = createCallbackFcn(app, @random_btn_Callback, true);
            app.random_btn.Tag = 'random_btn';
            app.random_btn.Text = 'Randomize';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RobotControl_GUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)RobotControl_GUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end